classdef app13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LAGRANGEUIFigure             matlab.ui.Figure
        RESULTADOSPanel              matlab.ui.container.Panel
        LimpiarButton                matlab.ui.control.Button
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        EcuaciondeLagrangeEditField  matlab.ui.control.EditField
        EcuaciondeLagrangeEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        EJECUTARButton               matlab.ui.control.Button
        INGRESODEDATOSPanel          matlab.ui.container.Panel
        Button                       matlab.ui.control.Button
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        IMPORTARDATOSButton          matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = public)
        t;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Xx=[0 1 3 4 6 7 ];
            Yy=[-5 1 25 55 181 289];
            uni = [Xx ; Yy]';
           app.UITable.Data = array2table(uni);
           
           app.XEditField.Value = 2.5;


        end

        % Button pushed function: IMPORTARDATOSButton
        function IMPORTARDATOSButtonPushed(app, event)
            [FileName,Directorio] = uigetfile('*.xlsx','Seleccionar datos');
                    if FileName == 0
                        return;
                        
                    else
                          DataL = strcat(Directorio,FileName);
                          app.t = readtable(DataL) ;
                          app.UITable.Data = app.t ;
                    end


        end

        % Button pushed function: EJECUTARButton
        function EJECUTARButtonPushed(app, event)
           cla(app.UIAxes);
           
            [filas,~] = size(app.UITable.Data);
            
                   app.UITable.Data = unique (app.UITable.Data);
                    
                    for i=1:filas    
                       if table2array(app.UITable.Data(i,:)) == 0 
                            app.UITable.Data(i,:) = [];    
                            break;        
                       end   
                    end      
            
            
            app.t = app.UITable.Data;
            m = table2array(app.t);
            syms x            
            X = m(:,1)';            
            Y = m(:,2)';            
            
            nn={'y';'y'};
            dd={'y' 'y'};
            L_n=str2sym(nn);
            L_d=str2sym(dd);
            
            num = length(Y);          
                                   
            for j=1:num
                     C = nchoosek(X,num-1);         
                     numerador = x-C(j,:);    
                     bb = expand(prod(numerador));         
                     L_n(j) = bb   ;      
            end                  
                 
            for k=1:num      
                   D = flipud(nchoosek(X,num-1)  );
                   denominador = X(k)-D(k,:);
                   cc =  prod(denominador);
                   L_d(k) = cc    ;                                             
            end
                 
            nume =  L_n  ;
            deno =  (flipud(L_d')) ; 
            lagrange =simplify(flipud(nume./deno));
            
            final_lagrange = simplify(sum(Y'.*lagrange)) ;
            
            app.EcuaciondeLagrangeEditField.Value = char(final_lagrange);
            
            hold(app.UIAxes,"on");
            fplot(app.UIAxes,final_lagrange);
            plot(app.UIAxes,X,Y,'o','MarkerFaceColor',[0.5,0.5,0.5],'MarkerSize',10);
            app.UIAxes.XAxisLocation = 'origin' ;
            app.UIAxes.YAxisLocation = 'origin' ;
  
            
            x = app.XEditField.Value;

            app.YEditField.Value =  eval ( final_lagrange );
            
            plot(app.UIAxes,x,app.YEditField.Value,'o','MarkerFaceColor','m','MarkerSize',10);

        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes);
            app.UITable.Data = [];
            app.EcuaciondeLagrangeEditField.Value = ' ';
            app.XEditField.Value = 0;
            app.YEditField.Value = 0;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            new_fil = {0 0};
            app.UITable.Data = [app.t;new_fil];
            app.t = app.UITable.Data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LAGRANGEUIFigure = uifigure('Visible', 'off');
            app.LAGRANGEUIFigure.Name = 'LAGRANGE';
            app.INGRESODEDATOSPanel = uipanel(app.LAGRANGEUIFigure);
            app.INGRESODEDATOSPanel.Title = 'INGRESO DE DATOS';
            app.UITable = uitable(app.INGRESODEDATOSPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.IMPORTARDATOSButton = uibutton(app.INGRESODEDATOSPanel, 'push');
            app.IMPORTARDATOSButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTARDATOSButtonPushed, true);
            app.IMPORTARDATOSButton.Text = 'IMPORTAR DATOS';
            app.XEditFieldLabel = uilabel(app.INGRESODEDATOSPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.INGRESODEDATOSPanel, 'numeric');
            app.Button = uibutton(app.INGRESODEDATOSPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.EJECUTARButton = uibutton(app.LAGRANGEUIFigure, 'push');
            app.EJECUTARButton.ButtonPushedFcn = createCallbackFcn(app, @EJECUTARButtonPushed, true);
            app.EJECUTARButton.Text = 'EJECUTAR';
            app.RESULTADOSPanel = uipanel(app.LAGRANGEUIFigure);
            app.RESULTADOSPanel.Title = 'RESULTADOS';
            app.UIAxes = uiaxes(app.RESULTADOSPanel);
            title(app.UIAxes, 'Grafica de la Ecuación de Lagrange')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            app.EcuaciondeLagrangeEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.EcuaciondeLagrangeEditFieldLabel.Text = 'Ecuacion de Lagrange';
            app.EcuaciondeLagrangeEditField = uieditfield(app.RESULTADOSPanel, 'text');
            app.YEditFieldLabel = uilabel(app.RESULTADOSPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RESULTADOSPanel, 'numeric');
            app.LimpiarButton = uibutton(app.RESULTADOSPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.LAGRANGEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LAGRANGEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LAGRANGEUIFigure)
        end
    end
end